classdef T1optimizerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        progressGauge            matlab.ui.control.LinearGauge
        progressGaugeLabel       matlab.ui.control.Label
        MRILoadButton            matlab.ui.control.Button
        T1FileLocationLabel      matlab.ui.control.Label
        SelectfileButton         matlab.ui.control.Button
        SegmentationButton       matlab.ui.control.Button
        MakeameshButton          matlab.ui.control.Button
        PairedcomputationButton  matlab.ui.control.Button
        TIoptimizationButton     matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        SelectedNiiFile
        SelectedNiiFileName
        T1Data
        num_el
        reference
        node
        elem
        face
        hdrinfo
        electrode
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SliderValueChanged(app, event)

        end

        % Button pushed function: SelectfileButton
        function SelectfileButtonPushed(app, event)
            % select sensor coordinates file
            fileName = uigetfile('*.nii','Select a NIfTI file');
            if fileName ~= 0 % 사용자가 파일을 선택한 경우
                app.SelectedNiiFile = niftiread(fileName);
            end
            app.T1FileLocationLabel.Text = fileName;
            app.SelectedNiiFileName=fileName;

        end

        % Button pushed function: MRILoadButton
        function MRILoadButtonPushed(app, event)
            if isempty(app.SelectedNiiFile)
                errordlg('Please select a NIfTI file first.', 'Error');
                return;
            end
            app.T1Data = load_untouch_nii(app.SelectedNiiFileName); % call load_untouch_nii func

            % UIAxes 컴포넌트의 Handle을 얻어옵니다.
            ax = app.UIAxes; % app은 MATLAB App Designer에서 생성된 App의 이름입니다.

            %             sliceshow(app.T1Data.img, ax, 'gray', [], [], 'MRI: Click anywhere to navigate.');

            % NIfTI 3D 볼륨 데이터를 montage 함수로 표시합니다.
            h = montage(app.SelectedNiiFile, 'Parent', ax);

            % 가운데 슬라이스의 인덱스 계산
            middleSliceIdx = floor(size(app.SelectedNiiFile, 3) / 2);

            % 가운데 슬라이스 선택 및 표시
            middleSlice = app.SelectedNiiFile(:, :, middleSliceIdx);
            imshow(middleSlice, [], 'Parent', ax);

            % 타이틀을 설정합니다.
            title(ax, 'Middle Slice');
            disp("T1 Loaded.")
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            start_seg(app.SelectedNiiFileName,[]); %% app.SelectedNiiFileName가 있는 폴더에서 실행

            % TODO start_seg 화면 병합
%             start_seg(app.SelectedNiiFileName,app.UIAxes); %% app.SelectedNiiFileName가 있는 폴더에서 실행


            segTouchup(app.SelectedNiiFileName,[]); %% segmentation 된 이미지 보정

        end

        % Button pushed function: MakeameshButton
        function MakeameshButtonPushed(app, event)
            app.electrode = '10_10';

            if app.electrode == '10_10'
                load('81options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=81;
                app.reference=-3;
            else
                load('18options.mat')
                app.hdrinfo=electrodePlacement(app.SelectedNiiFileName,app.SelectedNiiFileName,[],{'F3';'F4'},options,int2str(1));
                app.num_el=18;
                app.reference=-3;
            end

            [app.node,app.elem,app.face] = meshByIso2mesh(app.SelectedNiiFileName,app.SelectedNiiFileName,[],options.meshOpt,app.hdrinfo,int2str(1)); %% mesh 생성
        end

        % Button pushed function: PairedcomputationButton
        function PairedcomputationButtonPushed(app, event)


            prepareForGetDP(app.SelectedNiiFileName,app.node,app.elem,{'F3';'F4'},int2str(1),app.num_el); %% solve하기 전에 하는거
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TIoptimizationButton = uibutton(app.UIFigure, 'push');
            app.TIoptimizationButton.Text = 'TI optimization';
            app.PairedcomputationButton = uibutton(app.UIFigure, 'push');
            app.PairedcomputationButton.ButtonPushedFcn = createCallbackFcn(app, @PairedcomputationButtonPushed, true);
            app.PairedcomputationButton.Text = 'Paired computation';
            app.MakeameshButton = uibutton(app.UIFigure, 'push');
            app.MakeameshButton.ButtonPushedFcn = createCallbackFcn(app, @MakeameshButtonPushed, true);
            app.MakeameshButton.Text = 'Make a mesh';
            app.SegmentationButton = uibutton(app.UIFigure, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.SelectfileButton = uibutton(app.UIFigure, 'push');
            app.SelectfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfileButtonPushed, true);
            app.SelectfileButton.Text = 'Select file';
            app.T1FileLocationLabel = uilabel(app.UIFigure);
            app.T1FileLocationLabel.Text = 'T1 File Location';
            app.MRILoadButton = uibutton(app.UIFigure, 'push');
            app.MRILoadButton.ButtonPushedFcn = createCallbackFcn(app, @MRILoadButtonPushed, true);
            app.MRILoadButton.Text = 'MRI Load';
            app.progressGaugeLabel = uilabel(app.UIFigure);
            app.progressGaugeLabel.Text = 'progress';
            app.progressGauge = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1optimizerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end